classdef FinApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FINAppUIFigure  matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        UITable         matlab.ui.control.Table
        Label           matlab.ui.control.Label
        Card            matlab.ui.control.DropDown
        Label_2         matlab.ui.control.Label
        FromDate        matlab.ui.control.DatePicker
        Label_3         matlab.ui.control.Label
        ToDate          matlab.ui.control.DatePicker
        Summury         matlab.ui.control.Label
        OpenFile        matlab.ui.control.Button
        FnameLabel      matlab.ui.control.Label
        Tab_2           matlab.ui.container.Tab
        Ax2             matlab.ui.control.UIAxes
        Ax3             matlab.ui.control.UIAxes
        Label_4         matlab.ui.control.Label
        Category        matlab.ui.control.DropDown
        Reset           matlab.ui.control.Button
        Tab_3           matlab.ui.container.Tab
        Label_5         matlab.ui.control.Label
        NamesCats       matlab.ui.control.ListBox
        Label_6         matlab.ui.control.Label
        RuleType        matlab.ui.control.DropDown
        PreviewLabel    matlab.ui.control.Label
        Preview         matlab.ui.control.ListBox
        Label_8         matlab.ui.control.Label
        NewValue        matlab.ui.control.DropDown
        Rules           matlab.ui.control.Table
        Apply           matlab.ui.control.Button
    end

    
    properties (Access = private)
        T % Data table
        To
        fname % excel file
        R % Rules
        selR % Selected rule
    end
    
    methods (Access = private)
        
        function h = bar(app, ax, T)
            T = sortrows(T, 'sum_Sum', 'descend');
            h = bar(ax, T.sum_Sum);
            xticklabels(ax, string(T{:,1}))
            xtickangle(ax, 90)
            xticks(ax, 1 : length(T{:,1}));
            if length(h.XData) > 1
                text(ax, (1:height(T)), T.sum_Sum, string(round(T.sum_Sum)), 'vert','bottom','horiz','center');
            end
        end
        
        function fpath = get_latest(~)
            fs = struct2table(dir('D://downloads/operations*.xls'));
            if ~isempty(fs)
                fs.date = datetime(fs.datenum, 'ConvertFrom', 'datenum');
                fs = sortrows(fs, 'date', 'descend');
                fpath = fullfile(fs.folder{1}, fs.name{1});
            else
                fpath = 0;
            end
        end
        
        function T = recat(~, T, old, new)
            T.Cat = mergecats(T.Cat, old, new);
        end
        
        function T = rename(~, T, old, new)
            T.Name = mergecats(T.Name, old, new);
        end
        
        function T = switchcat(~, T, desc, cat)
            T.Cat(ismember(T.Name, desc)) = cat;
        end
        
        function T = prettify(app, T)
            R = app.R;
            if ~isempty(R)
                for i = 1 : height(R)
                    switch R.Type{i}
                        case "RC"
                            T = app.recat(T, R.Old{i}, R.New{i});
                        case "RN"
                            T = app.rename(T, R.Old{i}, R.New{i});
                        otherwise % SC
                            T = app.switchcat(T, R.Old{i}, R.New{i});
                    end
                end
            end
        end
        
        function T = load_data(app)
            T = readtable(app.fname, 'ReadVariableNames', false);
            if isempty(app.Card.Value)
                cards = T{:, 3};
                [G,ID] = findgroups(cards);
                app.Card.Items = ID;
                app.Card.Value = ID{mode(G)};
            end
            T = T(T{:, 3} == string(app.Card.Value), :);
            T = T(:, [1 4 5 10 12]);
            T = T(T{:, 2} == "OK", :);
            T(:, 2) = [];
            T.Properties.VariableNames = {'Date', 'Sum', 'Cat', 'Name'};
            T.Cat = categorical(T.Cat);
            T.Date = datetime(T.Date, 'InputFormat', 'dd.MM.uuuu HH:mm:ss');
            T.Name = categorical(T.Name);
            T = app.prettify(T);
            T = T(T.Date >= app.FromDate.Value, :);
            T.Sum = str2double(T.Sum);
            T.In = T.Sum > 0;
            T.Name = removecats(T.Name);
            T.Cat = removecats(T.Cat);
            if ~isempty(T)
                app.UITable.Data = T;
            else
                app.UITable.Data = [];
            end
            app.T = T;
            app.UITable.ColumnName = T.Properties.VariableNames;
            app.calc_summary();
            app.RuleTypeValueChanged();
        end
        
        function calc_summary(app)
            T = app.T;
            if ~isempty(T)
                T1 = groupsummary(T, 'In', 'sum', 'Sum');
                app.Summury.Text = "+" + T1.sum_Sum(2) + "/-" + abs(T1.sum_Sum(1));
                %             pie(app.Ax1, abs(T1.sum_Sum), ["Out"; "In"] + ": " + T1.sum_Sum);
                To = T(~T.In, :);
                To.Sum = -To.Sum;
                To.Cat = removecats(To.Cat);
                app.To = To;
                T2 = groupsummary(To, 'Cat', 'sum', 'Sum');
                T2 = sortrows(T2, 'sum_Sum', 'descend');
                app.Category.Items = string(T2.Cat);
                h = app.bar(app.Ax2, T2);
                h.FaceColor = 'flat';
                h.ButtonDownFcn = @app.clickBar;
                title(app.Ax2, "Итог: " + round(sum(T2.sum_Sum)));
            else
                app.Ax2.cla();
                app.Ax3.cla();
            end
        end
        
        function R = load_rules(app)
            fname = 'rules.mat';
            if isfile(fname)
                data = load(fname);
                R = data.R;
            else
                R = cell2table(cell(0, 3), 'VariableNames', ["Type", "Old", "New"]);
            end
            app.R = R;
        end
        
        function R = save_rules(app)
            fname = 'rules.mat'
            R = app.R;
            save(fname, 'R');
        end
        
        function update_rules(app)
            if ~isempty(app.R)
                app.R = sortrows(app.R, 'Type');
                app.Rules.Data = app.R;
                app.Rules.ColumnName = app.R.Properties.VariableNames;
            else
                app.Rules.Data = {};
            end
        end
        
        function main(app)
            app.ToDate.Value = datetime('now');
            if app.fname
                [~, fname, fext] = fileparts(app.fname);
                app.FnameLabel.Text = "Загружен: " + fname + fext;
                app.load_rules();
                app.load_data();
                app.CategoryValueChanged();
                app.update_rules();
            else
                app.FnameLabel.Text = 'Откройте файл';
            end
        end
        
        function clickBar(app, b, event)
            p = event.IntersectionPoint;
            x = [b.XData - b.BarWidth/2; b.XData + b.BarWidth/2]';
            num = find(p(1)>=x(:,1) & p(1)<=x(:,2));
            if ~isempty(num)
                cat = b.Parent.XTickLabel{num};
                if app.Category.Value ~= string(cat)
                    app.Category.Value = cat;
                    app.CategoryValueChanged();
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fname = app.get_latest();
            app.main();
        end

        % Value changed function: Card, FromDate, ToDate
        function CardValueChanged(app, event)
            app.load_data();
        end

        % Value changed function: Category
        function CategoryValueChanged(app, event)
            CatName = app.Category.Value;
            b = findobj(app.Ax2.Children, 'Type', 'bar');
            b.CData = repmat([0 0.4470 0.7410], length(b.XData), 1);
            b.CData(app.Category.Items == string(CatName), :) = [0.72 0.27 1];
            To = app.To;
            T3 = To(To.Cat == CatName, :);
            T3 = groupsummary(T3, 'Name', 'sum', 'Sum');
            app.Ax3.reset();
            app.bar(app.Ax3, T3);
            title(app.Ax3, CatName + ": " + round(sum(T3.sum_Sum)));
        end

        % Value changed function: NamesCats
        function NamesCatsValueChanged(app, event)
            value = app.NamesCats.Value;
            if app.RuleType.Value == "RC"
                T = app.T(ismember(app.T.Cat, value), 'Name');
                label = 'Названия';
                items = T.Name;
            else
                T = app.T(ismember(app.T.Name, value), 'Cat');
                label = 'Категории';
                items = T.Cat;
            end
            app.PreviewLabel.Text = label;
            app.Preview.Items = categories(removecats(items));
            app.Apply.Text = '>';
            app.selR = [];
        end

        % Value changed function: RuleType
        function RuleTypeValueChanged(app, event)
            value = app.RuleType.Value;
            if value == "RC"
                label = "Категории";
                labelpr = "Названия";
                items1 = app.T.Cat;
            else
                label = "Названия";
                labelpr = "Категории";
                items1 = app.T.Name;
            end
            if ~isempty(app.R)
                items = [];
                if value == "RC"
                    R = app.R(app.R.Type == "RC", :);
                    if ~isempty(R)
                        items = horzcat(R.Old{:})';
                    end
                    items = [items; R.New];
                    R = app.R(app.R.Type == "SC", :);
                    items = [items; R.New];
                else
                    R = app.R(app.R.Type ~= "RC", :);
                    if ~isempty(R)
                        items = horzcat(R.Old{:})';
                    end
                    R = app.R(app.R.Type == "RN", :);
                    items = [items; R.New];
                end
                items1 = unique([items1; items]);
            end
            if value == "RN"
                items2 = app.T.Name;
            else
                items2 = app.T.Cat;
            end
            app.Label_5.Text = label;
            app.PreviewLabel.Text = labelpr;
            app.NamesCats.Items = sort(categories(items1));
            app.NewValue.Items = sort([{''}; categories(items2)]);
            app.Apply.Text = '>';
            app.selR = [];
        end

        % Button pushed function: Apply
        function ApplyButtonPushed(app, event)
            if app.Apply.Text == ">"
                rule = {app.RuleType.Value, {app.NamesCats.Value}, app.NewValue.Value};
                app.R = [app.R; rule];
            elseif ~isempty(app.selR)
                app.R(app.selR, :) = [];
            end
            app.RuleTypeValueChanged();
            app.update_rules();
            app.save_rules();
            app.load_data();
        end

        % Cell selection callback: Rules
        function RulesCellSelection(app, event)
            i = event.Indices(1);
            app.RuleType.Value = app.R.Type{i};
            app.RuleTypeValueChanged();
            app.NamesCats.Value = app.R.Old{i};
            app.NewValue.Value = app.R.New{i};
            app.Apply.Text = '<';
            app.selR = i;
        end

        % Button pushed function: OpenFile
        function OpenFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xls;*.xlsx;*.csv');
            if file
                app.fname = fullfile(path, file);
                app.main();
            end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.calc_summary();
            app.CategoryValueChanged();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FINAppUIFigure = uifigure;
            app.FINAppUIFigure.Name = 'FIN App';
            app.TabGroup = uitabgroup(app.FINAppUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Данные';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''};
            app.UITable.RowName = {};
            app.Label = uilabel(app.Tab);
            app.Label.Text = 'Карта';
            app.Card = uidropdown(app.Tab);
            app.Card.Items = {};
            app.Card.Editable = 'on';
            app.Card.ValueChangedFcn = createCallbackFcn(app, @CardValueChanged, true);
            app.Card.Value = {};
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = 'С';
            app.FromDate = uidatepicker(app.Tab);
            app.FromDate.ValueChangedFcn = createCallbackFcn(app, @CardValueChanged, true);
            app.FromDate.Value = datetime([2018 9 25]);
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = 'По';
            app.ToDate = uidatepicker(app.Tab);
            app.ToDate.ValueChangedFcn = createCallbackFcn(app, @CardValueChanged, true);
            app.ToDate.Value = datetime([2018 9 25]);
            app.Summury = uilabel(app.Tab);
            app.Summury.Text = 'Summary';
            app.OpenFile = uibutton(app.Tab, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFile.Text = 'Открыть файл';
            app.FnameLabel = uilabel(app.Tab);
            app.FnameLabel.Text = 'Fname';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Расходы';
            app.Ax2 = uiaxes(app.Tab_2);
            title(app.Ax2, 'Итог')
            app.Ax2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Ax3 = uiaxes(app.Tab_2);
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = 'Категория';
            app.Category = uidropdown(app.Tab_2);
            app.Category.ValueChangedFcn = createCallbackFcn(app, @CategoryValueChanged, true);
            app.Reset = uibutton(app.Tab_2, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Перестроить';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Правила';
            app.Label_5 = uilabel(app.Tab_3);
            app.Label_5.Text = 'Категории';
            app.NamesCats = uilistbox(app.Tab_3);
            app.NamesCats.Multiselect = 'on';
            app.NamesCats.ValueChangedFcn = createCallbackFcn(app, @NamesCatsValueChanged, true);
            app.NamesCats.Value = {'Item 1'};
            app.Label_6 = uilabel(app.Tab_3);
            app.Label_6.Text = 'Правило';
            app.RuleType = uidropdown(app.Tab_3);
            app.RuleType.Items = {'Переименовать категорию (RC)', 'Изменить название (RN)', 'Сменить категорию (SC)'};
            app.RuleType.ItemsData = {'RC', 'RN', 'SC'};
            app.RuleType.ValueChangedFcn = createCallbackFcn(app, @RuleTypeValueChanged, true);
            app.RuleType.Value = 'RC';
            app.PreviewLabel = uilabel(app.Tab_3);
            app.PreviewLabel.Enable = 'off';
            app.PreviewLabel.Text = 'Preview';
            app.Preview = uilistbox(app.Tab_3);
            app.Preview.Items = {};
            app.Preview.Enable = 'off';
            app.Preview.Value = {};
            app.Label_8 = uilabel(app.Tab_3);
            app.Label_8.Text = {'Новое'; 'значение'};
            app.NewValue = uidropdown(app.Tab_3);
            app.NewValue.Editable = 'on';
            app.Rules = uitable(app.Tab_3);
            app.Rules.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Rules.RowName = {};
            app.Rules.CellSelectionCallback = createCallbackFcn(app, @RulesCellSelection, true);
            app.Apply = uibutton(app.Tab_3, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.Apply.Text = '>';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FinApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FINAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FINAppUIFigure)
        end
    end
end